classdef JAMPS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        panImport                   matlab.ui.container.Panel
        BrowsePanel                 matlab.ui.container.Panel
        SelectfilexlsorxlsxLabel    matlab.ui.control.Label
        ImportselectedfileLabel     matlab.ui.control.Label
        SelectedfileLabel           matlab.ui.control.Label
        lblShowPath                 matlab.ui.control.Label
        btnBrowse                   matlab.ui.control.Button
        btnImport                   matlab.ui.control.Button
        panSelect                   matlab.ui.container.Panel
        AvailablemesurementsLabel   matlab.ui.control.Label
        lbox                        matlab.ui.control.ListBox
        InstructionsTextAreaLabel   matlab.ui.control.Label
        taInstructions              matlab.ui.control.TextArea
        btnUse                      matlab.ui.control.Button
        panPrecalculation           matlab.ui.container.Panel
        SelectedmeasurementsLabel   matlab.ui.control.Label
        NumberofmeasurementsLabel   matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        PlotoptionsLabel            matlab.ui.control.Label
        Switch                      matlab.ui.control.ToggleSwitch
        spinnReduce                 matlab.ui.control.Spinner
        UITable                     matlab.ui.control.Table
        lblReductionInfoPercent     matlab.ui.control.Label
        lblReductionInfo            matlab.ui.control.Label
        lblReducePercent            matlab.ui.control.Label
        swReduce                    matlab.ui.control.Switch
        ReductionSwitchLabel        matlab.ui.control.Label
        MonitorButton               matlab.ui.control.Button
        btnAdvanced                 matlab.ui.control.Button
        btnImportPre                matlab.ui.control.Button
        panAdvanced                 matlab.ui.container.Panel
        MaxiterationEditFieldLabel  matlab.ui.control.Label
        etxtMaxIteration            matlab.ui.control.NumericEditField
        cbProgress                  matlab.ui.control.CheckBox
        MonitorsettingsduringcalculationLabel  matlab.ui.control.Label
        cbPlot                      matlab.ui.control.CheckBox
        cbValues                    matlab.ui.control.CheckBox
        SolverconfigurationLabel    matlab.ui.control.Label
        btnApply                    matlab.ui.control.Button
        btnBack                     matlab.ui.control.Button
        SolverLabel                 matlab.ui.control.Label
        ddSolver                    matlab.ui.control.DropDown
        cbMs                        matlab.ui.control.CheckBox
        LowerboundaryMstomaxLabel   matlab.ui.control.Label
        panMonitor                  matlab.ui.container.Panel
        panPlotRes                  matlab.ui.container.Panel
        plotMonitor                 matlab.ui.control.UIAxes
        panValuesRes                matlab.ui.container.Panel
        txtValues                   matlab.ui.control.TextArea
        ProgressPanel               matlab.ui.container.Panel
        Gauge                       matlab.ui.control.SemicircularGauge
        btnImprove                  matlab.ui.control.Button
        btnPreMonitor               matlab.ui.control.Button
        btnResults                  matlab.ui.control.Button
        btnOptimize                 matlab.ui.control.Button
        panResult                   matlab.ui.container.Panel
        tabResult                   matlab.ui.control.Table
        plotResult                  matlab.ui.control.UIAxes
        JilesAthertonparametervaluesLabel  matlab.ui.control.Label
        ddChooseRes                 matlab.ui.control.DropDown
        swChooseRes                 matlab.ui.control.ToggleSwitch
        btnMonitorPlot              matlab.ui.control.Button
        btnSave                     matlab.ui.control.Button
        PlotresultLabel             matlab.ui.control.Label
        swErrorPlot                 matlab.ui.control.ToggleSwitch
        txtaError                   matlab.ui.control.TextArea
        ErrorLabel                  matlab.ui.control.Label
        cbAddComment                matlab.ui.control.CheckBox
        lblAreaInfo                 matlab.ui.control.Label
        txtaComment                 matlab.ui.control.TextArea
        lblComment                  matlab.ui.control.Label
        panExist                    matlab.ui.container.Panel
        edtSaveAs                   matlab.ui.control.EditField
        btnSaveNew                  matlab.ui.control.Button
        swOverride                  matlab.ui.control.ToggleSwitch
        lblSaveAs                   matlab.ui.control.Label
        btnResExist                 matlab.ui.control.Button
        GuidePanel                  matlab.ui.container.Panel
        txtaGuide                   matlab.ui.control.TextArea
    end


    properties (Access = private)
        fileName % Description
        impData % Description
        numImpRows % Description
        msgNumMeas % Description
        numLoopRows % Description
        Hyst1 % Hyst1 raw data
        Hyst2 % Hyst2 raw data
        Hyst3 % Hyst3 raw data
        Hyst4 % Hyst4 raw data
        selectedLoopCellString % Description
        selectedLoopArray % Description
        reductionFactor % Description
        redH1 % Description
        redH2 % Description
        redH3 % Description
        redH4 % Description
        BsimT1 % Description
        BsimT2 % Description
        BsimT3 % Description
        BsimT4 % Description
        maxIterations % Description
        showPlot % Description
        showProgress % Description
        showValues % Description
        iteration % Description
        monitorString % Description
        
        res % Description
        solverType % Description
        totMatlabArea % Description
        totMeasArea % Description
        totAllArea % Description
        totPercDif % Description
        minMs % Description
    end

    methods (Access = private)
    
        function plotLoops(app)
            [row, col] = size(app.selectedLoopArray);
            value = app.DropDown.Value;
            
            if strcmp(app.Switch.Value, 'All')
               switch col
                case 1
                    scatter(app.UIAxes, app.redH1(:,1), app.redH1(:,2));
                    app.UITable.Data = [size(app.redH1,1)];
                case 2
                    scatter(app.UIAxes, app.redH1(:,1), app.redH1(:,2));
                    hold(app.UIAxes,'on');
                    scatter(app.UIAxes, app.redH2(:,1), app.redH2(:,2));
                    hold(app.UIAxes,'off');
                    
                    app.UITable.Data = [size(app.redH1,1), size(app.redH2,1)];
                    
                case 3
                    scatter(app.UIAxes, app.redH1(:,1), app.redH1(:,2));
                    hold(app.UIAxes,'on');
                    scatter(app.UIAxes, app.redH2(:,1), app.redH2(:,2));
                    scatter(app.UIAxes, app.redH3(:,1), app.redH3(:,2));
                    hold(app.UIAxes,'off');
                    
                    app.UITable.Data = [size(app.redH1,1), size(app.redH2,1), size(app.redH3,1)];
                case 4
                    scatter(app.UIAxes, app.redH1(:,1), app.redH1(:,2));
                    hold(app.UIAxes,'on');
                    scatter(app.UIAxes, app.redH2(:,1), app.redH2(:,2));
                    scatter(app.UIAxes, app.redH3(:,1), app.redH3(:,2));
                    scatter(app.UIAxes, app.redH4(:,1), app.redH4(:,2));
                    hold(app.UIAxes,'off');
                    
                    app.UITable.Data = [size(app.redH1,1), size(app.redH2,1), size(app.redH3,1), size(app.redH4,1)];
                end
            else
                for i = 1:1:col
                    if strcmp(value,app.selectedLoopCellString(i,1))
                        switch i
                            case 1
                                scatter(app.UIAxes, app.redH1(:,1), app.redH1(:,2));
                            case 2
                                scatter(app.UIAxes, app.redH2(:,1), app.redH2(:,2));
                            case 3
                                scatter(app.UIAxes, app.redH3(:,1), app.redH3(:,2));
                            case 4
                                scatter(app.UIAxes, app.redH4(:,1), app.redH4(:,2));
                        end
                    end
                end    
            end
        end
        
        function reduceMeasurements(app)
            app.reductionFactor = app.spinnReduce.Value;
            [row, col] = size(app.selectedLoopArray);
            mi0=4.*pi.*1e-7;
            
            switch col
                case 1
                    app.redH1 = [app.Hyst1(1:app.reductionFactor:end,1),app.Hyst1(1:app.reductionFactor:end,2)];
                case 2
                    app.redH1 = [app.Hyst1(1:app.reductionFactor:end,1),app.Hyst1(1:app.reductionFactor:end,2)];
                    app.redH2 = [app.Hyst2(1:app.reductionFactor:end,1),app.Hyst2(1:app.reductionFactor:end,2)];
                    
                case 3
                    app.redH1 = [app.Hyst1(1:app.reductionFactor:end,1),app.Hyst1(1:app.reductionFactor:end,2)];
                    app.redH2 = [app.Hyst2(1:app.reductionFactor:end,1),app.Hyst2(1:app.reductionFactor:end,2)];
                    app.redH3 = [app.Hyst3(1:app.reductionFactor:end,1),app.Hyst3(1:app.reductionFactor:end,2)];
                case 4
                    app.redH1 = [app.Hyst1(1:app.reductionFactor:end,1),app.Hyst1(1:app.reductionFactor:end,2)];
                    app.redH2 = [app.Hyst2(1:app.reductionFactor:end,1),app.Hyst2(1:app.reductionFactor:end,2)];
                    app.redH3 = [app.Hyst3(1:app.reductionFactor:end,1),app.Hyst3(1:app.reductionFactor:end,2)];
                    app.redH4 = [app.Hyst4(1:app.reductionFactor:end,1),app.Hyst4(1:app.reductionFactor:end,2)];
            end
        end
        
        function multipleLoopsDifferentLength(app, improve)
            %clc
            %mi0=4.*pi.*1e-7;
            modelType = 1;
            
            
            app.iteration = 1;
            %This is to plot in monitor during optimization
            
            %Enter maximum number of iterations in optimization
            maxIteration = app.maxIterations;
            %Script options (1=only plot, 2=optimize)
            scriptOptions = 2;
            
            if improve
                Ms = setMinMs(app); %To check if min value is checked. Used in optimization.
                
                % to show order: app.res = [a,k,c,alpha, Ms];
                
                a=round(app.res(1)); 
                k=round(app.res(2));
                c=round(app.res(3),5);
                alpha=round(app.res(4),5);
                Ms=round(app.res(5));
            else
                % Initial values
%                 Res = [199,842,0.06,5.2843e-4,1.152e6]; % First result from Comsol
                Res = [199,800,0.5,5.2843e-4,1.152e7]; % First result from Comsol
                a=Res(1,1); %tilts both curves
                k=Res(1,2); %shapes desc 2. og asc 2.
                c=Res(1,3);
                alpha=Res(1,4);
                %Ms = Res(1,5); %inital guess was 1e6
                Ms = setMinMs(app);
            end


            switch(scriptOptions)
                case 1 
%                     % Rod 25175
%                     Ms=974672.8;
%                     a=1131.16; 
%                     k=2348.45;
%                     c=0.2145793;
%                     alpha=0.000836528973824;
                    
                    % Rod 6
                    Ms=1427100.592;
                    a=533.7758585; 
                    k=456.34;
                    c=0.402872374;
                    alpha=0.000782366;
                    
                    
                case 2
                    options=optimset('Display','iter','MaxFunEvals',maxIteration);
                    
                    %HmeasT, Ms, ModelType, a, k, c, alpha
                    optiFun1 = @(b) areaDif(app, app.redH1, app.redH2, app.redH3, app.redH4, modelType, app.solverType, b(1), b(2), b(3), b(4), b(5));
                    
                    
                    b_init = [a k c alpha Ms];
                    b_min = fminsearch(optiFun1, b_init, options);
                    a = b_min(1);
                    k = b_min(2);
                    c = b_min(3);
                    alpha = b_min(4);
                    Ms = b_min(5);
                    
%                     fprintf('The result is:\n%6.2f %6.2f %6.2f %6.2f %6.2f', a,k,c,alpha,Ms);
                    
            end


            % BsimT = JAn_loops(Hyst1(:,1),199,842,0.0011,1.152e6,5.2843e-4,modelType);
            % Beste param for kurve 1


            [row, col] = size(app.selectedLoopArray);
            switch col
                case 1
                    app.BsimT1 = JAn_loops(app, app.redH1(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    
                    eRes1 = errorCalculation(app, app.redH1(:,1), app.redH1(:,2), app.BsimT1, false);
                    app.totMeasArea = eRes1(1);
                    app.totMatlabArea = eRes1(2);
                    app.totAllArea = app.totMeasArea + app.totMatlabArea;
                    
                    %Measured - Matlab
                    diff1 = abs(eRes1(1) - eRes1(2));
                    totDiff = diff1;
                    
                    app.totPercDif = 100.*(totDiff./app.totMeasArea);
                case 2
                    app.BsimT1 = JAn_loops(app, app.redH1(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    app.BsimT2 = JAn_loops(app, app.redH2(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    
                    eRes1 = errorCalculation(app, app.redH1(:,1), app.redH1(:,2), app.BsimT1, false);
                    eRes2 = errorCalculation(app, app.redH2(:,1), app.redH2(:,2), app.BsimT2, false);
                    app.totMeasArea = eRes1(1) + eRes2(1);
                    app.totMatlabArea = eRes1(2) +eRes2(2);
                    app.totAllArea = app.totMeasArea + app.totMatlabArea;
                    
                    %Measured - Matlab
                    diff1 = abs(eRes1(1) - eRes1(2));
                    diff2 = abs(eRes2(1) - eRes2(2));
                    totDiff = diff1 + diff2;
                    
                    app.totPercDif = 100.*(totDiff./app.totMeasArea);
                case 3
                    app.BsimT1 = JAn_loops(app, app.redH1(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    app.BsimT2 = JAn_loops(app, app.redH2(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    app.BsimT3 = JAn_loops(app, app.redH3(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    
                    eRes1 = errorCalculation(app, app.redH1(:,1), app.redH1(:,2), app.BsimT1, false);
                    eRes2 = errorCalculation(app, app.redH2(:,1), app.redH2(:,2), app.BsimT2, false);
                    eRes3 = errorCalculation(app, app.redH3(:,1), app.redH3(:,2), app.BsimT3, false);
                    app.totMeasArea = eRes1(1) + eRes2(1) + eRes3(1);
                    app.totMatlabArea = eRes1(2) +eRes2(2) + eRes3(2);
                    app.totAllArea = app.totMeasArea + app.totMatlabArea;
                    
                    %Measured - Matlab
                    diff1 = abs(eRes1(1) - eRes1(2));
                    diff2 = abs(eRes2(1) - eRes2(2));
                    diff3 = abs(eRes3(1) - eRes3(2));
                    totDiff = diff1 + diff2 + diff3;
                    
                    app.totPercDif = 100.*(totDiff./app.totMeasArea);
                case 4
                    app.BsimT1 = JAn_loops(app, app.redH1(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    app.BsimT2 = JAn_loops(app, app.redH2(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    app.BsimT3 = JAn_loops(app, app.redH3(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    app.BsimT4 = JAn_loops(app, app.redH4(:,1),a,k,c,Ms,alpha, modelType, app.solverType);
                    
                    eRes1 = errorCalculation(app, app.redH1(:,1), app.redH1(:,2), app.BsimT1, false);
                    eRes2 = errorCalculation(app, app.redH2(:,1), app.redH2(:,2), app.BsimT2, false);
                    eRes3 = errorCalculation(app, app.redH3(:,1), app.redH3(:,2), app.BsimT3, false);
                    eRes4 = errorCalculation(app, app.redH4(:,1), app.redH4(:,2), app.BsimT4, false);
                    app.totMeasArea = eRes1(1) + eRes2(1) + eRes3(1) + eRes4(1);
                    app.totMatlabArea = eRes1(2) +eRes2(2) + eRes3(2) + eRes4(2);
                    app.totAllArea = app.totMeasArea + app.totMatlabArea;
                    
                    %Measured - Matlab
                    diff1 = abs(eRes1(1) - eRes1(2));
                    diff2 = abs(eRes2(1) - eRes2(2));
                    diff3 = abs(eRes3(1) - eRes3(2));
                    diff4 = abs(eRes4(1) - eRes4(2));
                    totDiff = diff1 + diff2 + diff3 + diff4;
                    
                    app.totPercDif = 100.*(totDiff./app.totMeasArea);
            end
            
                    
            msgError = sprintf('Total calculated areas:\nMeasured: %.2f\nMatlab: %.2f\nSum: %.2f\n\nAbsolute difference (%%): %.2f',app.totMeasArea, app.totMatlabArea, app.totAllArea, app.totPercDif);
            app.txtaError.Value = msgError;
            
            
            %plot
            plotComparisonResult(app);
            
            app.tabResult.Data = [a,k,c,alpha, Ms];
            app.res = [a,k,c,alpha, Ms];
            
            app.btnResults.Visible = 'on';
            app.btnResults.BackgroundColor = [0.20, 0.80, 0.20];

%             save -v7 res.mat Res %Må se an hvordan man kan lagre


        end
        
        function func = areaDif(app, hyst1, hyst2, hyst3, hyst4, modelType, solverType, a, k, c, alpha, Ms)
                disp('[a,k,c,alpha, Ms]')
                
                [row, col] = size(app.selectedLoopArray);
                
                switch col
                    case 1
                        BsimT1 = JAn_loops(app, hyst1(:,1),a,k,c,Ms,alpha, modelType, solverType);
                        tempVal = sum(abs(BsimT1-hyst1(:,2))).^(sum((hyst1(:,2)-BsimT1).^2));
                        
                        
                        tot = tempVal;
                    case 2
                        BsimT1 = JAn_loops(app, hyst1(:,1),a,k,c,Ms,alpha, modelType, solverType);
                        BsimT2 = JAn_loops(app, hyst2(:,1),a,k,c,Ms,alpha, modelType, solverType);
                        
                        tempVal = sum(abs(BsimT1-hyst1(:,2))).^(sum((hyst1(:,2)-BsimT1).^2));
                        tempVal2 = sum(abs(BsimT2-hyst2(:,2))).^(sum((hyst2(:,2)-BsimT2).^2));
%                         tempVal = sum(abs(BsimT1-hyst1(:,2)));
%                         tempVal2 = sum(abs(BsimT2-hyst2(:,2)));
                
                        tot = tempVal + tempVal2;
                    case 3
                        BsimT1 = JAn_loops(app, hyst1(:,1),a,k,c,Ms,alpha, modelType, solverType);
                        BsimT2 = JAn_loops(app, hyst2(:,1),a,k,c,Ms,alpha, modelType, solverType);
                        BsimT3 = JAn_loops(app, hyst3(:,1),a,k,c,Ms,alpha, modelType, solverType); 
                       
                        tempVal = sum(abs(BsimT1-hyst1(:,2))).^(sum((hyst1(:,2)-BsimT1).^2));
                        tempVal2 = sum(abs(BsimT2-hyst2(:,2))).^(sum((hyst2(:,2)-BsimT2).^2));
                        tempVal3 = sum(abs(BsimT3-hyst3(:,2))).^(sum((hyst3(:,2)-BsimT3).^2));
%                         tempVal = sum(abs(BsimT1-hyst1(:,2)));
%                         tempVal2 = sum(abs(BsimT2-hyst2(:,2)));
%                         tempVal3 = sum(abs(BsimT3-hyst3(:,2)));
                        
                        
                        tot = tempVal + tempVal2 + tempVal3;
                    case 4
                        BsimT1 = JAn_loops(app, hyst1(:,1),a,k,c,Ms,alpha, modelType, solverType);
                        BsimT2 = JAn_loops(app, hyst2(:,1),a,k,c,Ms,alpha, modelType, solverType);
                        BsimT3 = JAn_loops(app, hyst3(:,1),a,k,c,Ms,alpha, modelType, solverType); 
                        BsimT4 = JAn_loops(app, hyst4(:,1),a,k,c,Ms,alpha, modelType, solverType);
                       
                        tempVal = sum(abs(BsimT1-hyst1(:,2))).^(sum((hyst1(:,2)-BsimT1).^2));
                        tempVal2 = sum(abs(BsimT2-hyst2(:,2))).^(sum((hyst2(:,2)-BsimT2).^2));
                        tempVal3 = sum(abs(BsimT3-hyst3(:,2))).^(sum((hyst3(:,2)-BsimT3).^2));
                        tempVal4 = sum(abs(BsimT4-hyst4(:,2))).^(sum((hyst4(:,2)-BsimT4).^2));

%                         tempVal = sum(abs(BsimT1-hyst1(:,2)));
%                         tempVal2 = sum(abs(BsimT2-hyst2(:,2)));
%                         tempVal3 = sum(abs(BsimT3-hyst3(:,2)));
%                         tempVal4 = sum(abs(BsimT4-hyst4(:,2)));
                    
                        tot = tempVal + tempVal2 + tempVal3 + tempVal4;
                end
            
                
                %Monitor 
                res = [a,k,c,alpha,Ms];
                monitor(app, app.iteration, tot, res);
                app.iteration = app.iteration + 1;
                
                %Return
                func = tot;
        end
        
        function BsimT = JAn_loops(app, HmeasT,a,k,c,Ms,alpha,ModelType,SolverType,IsoAniso,AnisoType,Kan,psi,IntType)
            %
            % The MIT License (MIT)
            %
            % Copyright (c) 2017 Roman Szewczyk
            %
            % Permission is hereby granted, free of charge, to any person obtaining a copy
            % of this software and associated documentation files (the "Software"), to deal
            % in the Software without restriction, including without limitation the rights
            % to use, copy, modify, merge, publish, distribute, sublicense, and/or sell
            % copies of the Software, and to permit persons to whom the Software is
            % furnished to do so, subject to the following conditions:
            % 
            % The above copyright notice and this permission notice shall be included in all
            % copies or substantial portions of the Software.
            % 
            % THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR
            % IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY,
            % FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE
            % AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER
            % LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM,
            % OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE
            % SOFTWARE.
            % 
            %
            % DESCRIPTION:
            % JAn_loop solves Jiles-Atherton model for n hysteresis loops 
            %          (according to the numner of columns in HmeasT)
            %
            % AUTHOR: Roman Szewczyk, rszewczyk@onet.pl
            %
            % RELATED PUBLICATION(S):
            % [1]  Roman Szewczyk, Peng Cheng "Open source implementation of different variants of
            %      Jiles-Atherton model of magnetic hysteresis loops" Acta Physica Polonica A (submitted)
            %
            % USAGE:
            % BsimT = JAn_loops(a,k,c,Ms,alpha,Hstart,Hend,M0,SolverType)
            % 
            % IMPORTANT: H must be a COLUMN vector 
            %
            % INPUT:
            % HmeasT - magnetizing field, A/m (set of column vectors - matrix, A/m)
            % a    - quantifies domain density, A/m (scalar)
            % k    - quantifies average energy required to break pinning side, A/m (scalar)
            % c    - magnetization reversibility, 0..1 (scalar)
            % Ms   - saturation magnetization, A/m (scalar)
            % alpha - Bloch coefficient (scalar)
            %
            % ModelType - variation of the Jiles-Atherton model (please refer to [1])
            %             0 - dMah/dHe - DEFAULT
            %             1 - dMah/dH 
            %             2 - Venkataraman bulk magnetic hysteresis model 
            %
            % SolverType - select the solver for ODE
            %               0 - ode23() - DEFAULT
            %               1 - ode45()
            %               2 - ode23s()
            %               3 - rk4() 
            %
            % IsoAniso - isotropic or anisotropic model
            %             0 - isotropic model - DEFAULT
            %             1 - anisotropic model
            %
            % AnisoType - 
            %
            % Kan  - average magnetic anisotropy density, K/m3 (scalar)
            % psi  - the angle between a direction of the easy axis of magnetic anisotropy and the direction of the magnetizing field, rad (scalar)
            %
            % SolverType - select the solver for ODE
            %               0 - ode23() - DEFAULT
            %               1 - ode45()
            %               2 - ode23s()
            %               3 - rk4() 
            % 
            % IntType - select the solver for integration:
            %               0: quadtrapz() - DEFAULT
            %               1: quadgk()
            %
            % OUTPUT:
            % BsimT - set of output values of flux density B, T (vector)
            
            
            % determine default values for not determined variables
            if nargin<14 || isempty(IntType)
               IntType=0;
            end
            if nargin<13 || isempty(psi)
               psi=0;
            end
            if nargin<12 || isempty(Kan)
               Kan=0;
            end
            if nargin<11 || isempty(AnisoType)
               AnisoType=0;
            end
            if nargin<10 || isempty(IsoAniso)
               IsoAniso=0;
            end
            if nargin<9 || isempty(SolverType)
               SolverType=0;
            end
            if nargin<8 || isempty(ModelType)
               ModelType=0;
            end
            
            if ((c<0.05) || (c>1))%(false)%((a<0) || (k<0) || (c<0) || (c>1) || (alpha<0) || (Kan<0)) 
                fprintf('\n\n*** ERROR in JAn_loops: unphysical parameters.\n\n');
                BsimT=zeros(size(HmeasT));
                return
            end 
            
            if (Ms<app.minMs) 
                fprintf('\n\n*** ERROR in JAn_loops: unphysical parameters.\n\n');
                BsimT=zeros(size(HmeasT));
                return
            end            
            
            
            BsimT=zeros(size(HmeasT));                    % Prepare output variable
            
            InitialCurve=0;
            if sum(HmeasT(1,:))==0
                InitialCurve=1;
            end
            
            M0=0; % sample demagnetized at the beginning
            
            for lT=1:size(HmeasT,2)
            
             H=HmeasT(:,lT);
            %her er det noe rart
             if InitialCurve==0
                 H=[0; H];
             end                  % check if there is initial magnetization curve in H
             
             [Hw,Bw] = JAsingle_loop(app, H,a,k,c,Ms,alpha,ModelType,SolverType,IsoAniso,AnisoType,Kan,psi,IntType);
              
             if InitialCurve==0
                 Hw(1)=[];
                 Bw(1)=[];
                 H(1)=[];
                 
                 Bw=Bw+linspace(0,Bw(1)-Bw(numel(Bw)),numel(Bw))';       % Close hysteresis loop
                 Bw=Bw-(max(Bw)+min(Bw))./2    ;                         % Symmetrize Bw
                      
             end
             
             BsimT(:,lT)=Bw;          % Remember the result
            
            end
        end
        
        function [Hw,Bw] = JAsingle_loop(app, Hmeas,a,k,c,Ms,alpha,ModelType,SolverType,IsoAniso,AnisoType,Kan,psi,IntType)
            %
            % The MIT License (MIT)
            %
            % Copyright (c) 2016 Roman Szewczyk
            %
            % Permission is hereby granted, free of charge, to any person obtaining a copy
            % of this software and associated documentation files (the "Software"), to deal
            % in the Software without restriction, including without limitation the rights
            % to use, copy, modify, merge, publish, distribute, sublicense, and/or sell
            % copies of the Software, and to permit persons to whom the Software is
            % furnished to do so, subject to the following conditions:
            % 
            % The above copyright notice and this permission notice shall be included in all
            % copies or substantial portions of the Software.
            % 
            % THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR
            % IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY,
            % FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE
            % AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER
            % LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM,
            % OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE
            % SOFTWARE.
            % 
            %
            % DESCRIPTION:
            % JAsingle_loop splits the given magnetizing field H into monotonous sections and control solving
            % ODE stating Jiles-Atherton model
            %
            % AUTHOR: Roman Szewczyk, rszewczyk@onet.pl
            %
            % RELATED PUBLICATION(S):
            % [1] Jiles D. C., Atherton D. "Theory of ferromagnetic hysteresisâ€? Journal of Magnetism and Magnetic Materials 61 (1986) 48.
            % [2] Szewczyk R. "Computational problems connected with Jiles-Atherton model of magnetic hysteresis". Advances in Intelligent Systems and Computing (Springer) 267 (2014) 275.
            % USAGE:
            % [H,M] = JAsolver(a,k,c,Ms,alpha,Hstart,Hend,M0,SolverType)
            % 
            % IMPORTANT: H must be a COLUMN vector 
            %
            % INPUT:
            % Hmeas - magnetizing field, A/m (the column vector, A/m)
            % a    - quantifies domain density, A/m (scalar)
            % k    - quantifies average energy required to break pinning side, A/m (scalar)
            % c    - magnetization reversibility, 0..1 (scalar)
            % Ms   - saturation magnetization, A/m (scalar)
            % alpha - Bloch coefficient (scalar)
            %
            % ModelType - variation of the Jiles-Atherton model (please refer to [1])
            %             0 - dMah/dHe - DEFAULT
            %             1 - dMah/dH 
            %             2 - Venkataraman bulk magnetic hysteresis model 
            %
            % SolverType - select the solver for ODE
            %               0 - ode23() - DEFAULT
            %               1 - ode45()
            %               2 - ode23s()
            %               3 - rk4() 
            %
            % IsoAniso - isotropic or anisotropic model
            %             0 - isotropic model - DEFAULT
            %             1 - anisotropic model
            %
            % Kan  - average magnetic anisotropy density, K/m3 (scalar)
            % psi  - the angle between a direction of the easy axis of magnetic anisotropy and the direction of the magnetizing field, rad (scalar)
            %
            % SolverType - select the solver for ODE
            %               0 - ode23() - DEFAULT
            %               1 - ode45()
            %               2 - ode23s()
            %               3 - rk4() 
            % 
            % IntType - select the solver for integration:
            %               0: quadtrapz() - DEFAULT
            %               1: quadgk()
            %
            %
            % OUTPUT:
            % Hw - set of output values of magnetizing field H, A/m (vector)
            % Bw - set of output values of flux density B, T (vector)
            
            mi0=4.*pi.*1e-7;
            M0=0;
            H=Hmeas;
            
            
            % determine default values for not determined variables
            if nargin<14 || isempty(IntType)
               IntType=0;
            end
            if nargin<13 || isempty(psi)
               psi=0;
            end
            if nargin<12 || isempty(Kan)
               Kan=0;
            end
            if nargin<11 || isempty(AnisoType)
               AnisoType=0;
            end
            if nargin<10 || isempty(IsoAniso)
               IsoAniso=0;
            end
            if nargin<9 || isempty(SolverType)
               SolverType=0;
            end
            if nargin<8 || isempty(ModelType)
               ModelType=0;
            end
            
            
            if (false)%((a<0) || (k<0) || (c<0) || (c>1) || (alpha<0) || (Kan<0)) 
                fprintf('\n\n*** ERROR in JAsingle_loops: unphysical parameters.\n\n');
                Hw=H;
                Bw=zeros(size(H));
                return
            end           % check if parameters are physical
            
            
            if size(H,2)>1
               fprintf('\n\n***ERROR in JAsingle_loop: H must be the column vector\n\n');
               Bw=0;
               Hw=0;
               return
            end
            
            if numel(H)<2
               fprintf('\n\n***ERROR in JAsingle_loop: H must be at least two elements vector\n\n');
               Bw=0;
               Hw=0;
               return
            end
            
            if numel(H)==2
               [Hi, Mi]=JAsolver(app, a,k,c,Ms,alpha,H(ip),H(ik),M0,ModelType,SolverType,IsoAniso,AnisoType,Kan,psi,IntType);
               Hi(isnan(Hi))=1;
               Mi(isnan(Mi))=1;
               Hw=H;
               Mw=[M0 Mi(end)];
               Bw=Mw.*mi0+Hw.*mi0;
               return
            end
            
            
            ip=1;
            ik=2;
            
            Hw=zeros(size(H));
            Hw(1)=H(1);
            
            Mw=zeros(size(H));
            Mw(1)=M0;
            
            
            while ik<numel(H)
            
               if H(ik)>H(ip)
                  if H(ik+1)>=H(ik)
                     ik=ik+1;
                  else
                     [Hi, Mi]=JAsolver(app, a,k,c,Ms,alpha,H(ip),H(ik),M0,ModelType,SolverType,IsoAniso,AnisoType,Kan,psi,IntType);
                     Hi(isnan(Hi))=1;
                     Mi(isnan(Mi))=1;
                     if numel(Hi)>2
                        Mw(ip+1:ik)=interp1(Hi,Mi,H(ip+1:ik),'pchip');
                     else
                        Mw(ip+1)=Mi(end);
                     end
                     Hw(ip+1:ik)=H(ip+1:ik);
                     M0=Mi(end);
            
                     ip=ik;
                     ik=ip+1;
                  end
               end
            
               if H(ik)<H(ip)
                  if H(ik+1)<=H(ik)
                     ik=ik+1;
                     %fprintf('Inni loopen: %d\n\n', ik);
                  else
                     [Hi, Mi]=JAsolver(app, a,k,c,Ms,alpha,H(ip),H(ik),M0,ModelType,SolverType,IsoAniso,AnisoType,Kan,psi,IntType);
                     Hi(isnan(Hi))=1;
                     Mi(isnan(Mi))=1;
                     if numel(Hi)>2
                        Mw(ip+1:ik)=interp1(Hi,Mi,H(ip+1:ik),'pchip');
                     else
                        Mw(ip+1)=Mi(end);
                     end
                     Hw(ip+1:ik)=H(ip+1:ik);
                     M0=Mi(end);
                     
                     ip=ik;
                     ik=ip+1;
                  end
               end
               
               if H(ik)==H(ip)
                  ik=ik+1;
               end
            
            end
            
            [Hi, Mi]=JAsolver(app, a,k,c,Ms,alpha,H(ip),H(ik),M0,ModelType,SolverType,IsoAniso,AnisoType,Kan,psi,IntType);
            Hi(isnan(Hi))=1;
            Mi(isnan(Mi))=1;
            if numel(Hi)>2
               Mw(ip+1:ik)=interp1(Hi,Mi,H(ip+1:ik),'pchip');
            else
               Mw(ip+1)=Mi(end);
            end
            
            Hw(ip+1:ik)=H(ip+1:ik);
            M0=Mi(end);
            
            
            Bw=Mw.*mi0+Hw.*mi0;            
        end
        
        function [H,M] = JAsolver(app, a,k,c,Ms,alpha,Hstart,Hend,M0,ModelType,SolverType,IsoAniso,AnisoType,Kan,psi,IntType)
            % The MIT License (MIT)
            %
            % Copyright (c) 2016 Roman Szewczyk
            %
            % Permission is hereby granted, free of charge, to any person obtaining a copy
            % of this software and associated documentation files (the "Software"), to deal
            % in the Software without restriction, including without limitation the rights
            % to use, copy, modify, merge, publish, distribute, sublicense, and/or sell
            % copies of the Software, and to permit persons to whom the Software is
            % furnished to do so, subject to the following conditions:
            % 
            % The above copyright notice and this permission notice shall be included in all
            % copies or substantial portions of the Software.
            % 
            % THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR
            % IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY,
            % FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE
            % AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER
            % LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM,
            % OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE
            % SOFTWARE.
            % 
            %
            % DESCRIPTION:
            % JAsolver solves main ODE (ordinary differential equation) determining Jiles-Atherton model using different methods
            % Important: JAsolver requires monotonous changes in the magnetizing field H
            %
            % AUTHOR: Roman Szewczyk, rszewczyk@onet.pl
            %
            % RELATED PUBLICATION(S):
            % [1] Jiles D. C., Atherton D. "Theory of ferromagnetic hysteresisâ€? Journal of Magnetism and Magnetic Materials 61 (1986) 48.
            % [2] Szewczyk R. "Computational problems connected with Jiles-Atherton model of magnetic hysteresis". Advances in Intelligent Systems and Computing (Springer) 267 (2014) 275.
            %
            % USAGE:
            % [H,M] = JAsolver(a,k,c,Ms,alpha,Hstart,Hend,M0,SolverType)
            % 
            % IMPORTANT: H must be a scalar or COLUMN vector 
            %
            % INPUT:
            % a    - quantifies domain density, A/m (scalar)
            % k    - quantifies average energy required to break pinning side, A/m (scalar)
            % c    - magnetization reversibility, 0..1 (scalar)
            % Ms   - saturation magnetization, A/m (scalar)
            % alpha - Bloch coefficient (scalar)
            % Kan  - average magnetic anisotropy density, K/m3 (scalar)
            % psi  - the angle between a direction of the easy axis of magnetic anisotropy and the direction of the magnetizing field, rad (scalar)
            % H    - magnetizing field, A/m (scalar or column vector)
            % Hstart - minimal value of magnetization, A/m (scalar)
            % Hend - maximal value of magnetization, A/m (scalar)
            % M0   - initial value of magnetization for ODE, A/m (scalar)
            % SolverType - select the solver for ODE
            %               1 - ode23()
            %               2 - ode45()
            %               3 - ode23s()
            %               4 - rk4() 
            % FixedStep - select the solver for integration:
            %               1: quadtrapz(), 0: quadgk()
            %
            % OUTPUT:
            % H - set of output values of magnetizing field H, A/m (vector)
            % M - set of output values of magnetization M, A/m (vector)
            
            
            if Hend==Hstart
               H=[Hend Hstart]';
               M=[M0 M0]';
               return
            end
            
            options=odeset('RelTol',1e-4,'AbsTol',1e-6,'MaxStep',abs(Hend-Hstart)./10,'InitialStep',(Hend-Hstart)./10);    
              
            dMdH_=@(H,M) dMdH(app, a,k,c,Ms,alpha,M,H,Hstart,Hend,ModelType,IsoAniso,AnisoType,Kan,psi,IntType);
            
            switch (SolverType)
            
              case 1
                  try
                    [H,M] = ode23(dMdH_,[Hstart Hend],M0,options);
                  catch
                      H=[Hstart Hend];
                      M=[0 0];
                  end


              case 2
                [H,M] = ode45(dMdH_,[Hstart Hend],M0,options);
              
              case 3
                [H,M] = ode23s(dMdH_,[Hstart Hend],M0,options);
              
              case 4
                [H,M] = rk4(dMdH_,[Hstart Hend],M0,50);
            
                otherwise
                    try
                        [H,M] = ode23(dMdH_,[Hstart Hend],M0,options);
                    catch
                        H=[Hstart Hend];
                        M=[0 0];
                    end
            end             
        end
        
        function result = dMdH(app, a,k,c,Ms,alpha,M,H,Hstart,Hend,ModelType,IsoAniso,AnisoType,Kan,psi,IntType)
            %
            % The MIT License (MIT)
            %
            % Copyright (c) 2017 Roman Szewczyk, Peng Cheng
            %
            % Permission is hereby granted, free of charge, to any person obtaining a copy
            % of this software and associated documentation files (the "Software"), to deal
            % in the Software without restriction, including without limitation the rights
            % to use, copy, modify, merge, publish, distribute, sublicense, and/or sell
            % copies of the Software, and to permit persons to whom the Software is
            % furnished to do so, subject to the following conditions:
            % 
            % The above copyright notice and this permission notice shall be included in all
            % copies or substantial portions of the Software.
            % 
            % THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR
            % IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY,
            % FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE
            % AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER
            % LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM,
            % OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE
            % SOFTWARE.
            % 
            %
            % DESCRIPTION:
            % dMdH is the function for solving main ODE (ordinary differential equation) determining Jiles-Atherton model
            %
            % AUTHOR: Roman Szewczyk, rszewczyk@onet.pl, Peng Cheng, Peng.Cheng@miun.se
            %
            % RELATED PUBLICATION(S):
            % [1] Jiles D. C., Atherton D. "Theory of ferromagnetic hysteresisâ€� Journal of Magnetism and Magnetic Materials 61 (1986) 48.
            % [2] Chwastek K., Szczyglowski J. "Identification of a hysteresis model parameters with genetic algorithms" Mathematics and Computers in Simulation 71 (2006) 206.
            %
            % USAGE:
            % result = dMdH(a,k,c,Ms,alpha,Kan,psi,M,H,Hstart,Hend,FixedStep)
            % 
            % INPUT:
            % Hstart, Hend - magnetizing field, A/m (start and stop, A/m)
            % a    - quantifies domain density, A/m (scalar)
            % k    - quantifies average energy required to break pinning side, A/m (scalar)
            % c    - magnetization reversibility, 0..1 (scalar)
            % Ms   - saturation magnetization, A/m (scalar)
            % alpha - Bloch coefficient (scalar)
            %
            % ModelType - variation of the Jiles-Atherton model (please refer to [1])
            %             0 - dMah/dHe - DEFAULT
            %             1 - dMah/dH 
            %             2 - Venkataraman bulk magnetic hysteresis model 
            %
            % SolverType - select the solver for ODE
            %               0 - ode23() - DEFAULT
            %               1 - ode45()
            %               2 - ode23s()
            %               3 - rk4() 
            %
            % IsoAniso - isotropic or anisotropic model
            %             0 - isotropic model - DEFAULT
            %             1 - anisotropic model
            %
            % AnisoType - 
            %
            % Kan  - average magnetic anisotropy density, K/m3 (scalar)
            % psi  - the angle between a direction of the easy axis of magnetic anisotropy and the direction of the magnetizing field, rad (scalar)
            %
            % SolverType - select the solver for ODE
            %               0 - ode23() - DEFAULT
            %               1 - ode45()
            %               2 - ode23s()
            %               3 - rk4() 
            % 
            % IntType - select the solver for integration:
            %               0: quadtrapz() - DEFAULT
            %               1: quadgk()
            %
            % OUTPUT:
            % result - value of differential function in Jiles-Atherton model (scalar, vector or matrix, size of H)
            %
            
            if (max(size(a))>1 || max(size(k)>1) || max(size(c)>1) || max(size(Ms)>1) ...
               || max(size(alpha)>1) || max(size(Hstart)>1) || max(size(Hend)>1) ...
               || max(size(Kan)>1) || max(size(psi)>1))
             
              fprintf('\n*** ERROR in dMdH: among a, k, c, Kan, psi, Ms, alpha, Hstart, Hend at least one is not a scalar. ***\n');
              result=zeros(size(H));
              return;
              
            end
            
            if (size(H,1)~=size(M,1)) || (size(H,2)~=size(M,2))
             
              fprintf('\n*** ERROR in dMdH: size(H) is not equal size(M) ***\n');
              result=zeros(size(H));
              return;
              
            end
            
            Malpha=Mah_aniso(app, a,Ms,Kan,psi,H+alpha.*M,AnisoType,IntType);    % calculates anhysteretic anisotropic magnetization
                                                % to speed up further calculations
            
            dM1 = (Malpha-M);                   % first part of equation
            
            
            % Different types of Jiles-Atherton Model
            
            if ModelType==0   % dMah/dHe
            
               if Hend>Hstart
                  dM1(dM1<0)=0;
               end
            
               if Hend<Hstart
                  dM1(dM1>0)=0;
               end                                 % check physical condition as it is explained in publication [2]
            
               if Hend<Hstart
                  delta=-1;
               else
                  delta=1;
               end         % end of if             % calculate delta - increase or decrease H
            
            
               dM2 = (1+c).*(delta.*k-alpha.*(Malpha-M));  % second part of equation
            
               dM3 = c./(1+c).*dMah_aniso(app, a,Ms,Kan,psi,H+alpha.*M,AnisoType,IntType);  % third part of equation
            
               dM2(abs(dM2)<1e-70)=1e-70;
            
               result=dM1./dM2+dM3;                        % calculate the equation [1]
            
            
            end % of ModelType==0
               
               
            if ModelType==1   % dMah/dH
               dM1_=dM1;
               
               if Hend>Hstart
                  dM1(dM1<0)=0;
               end
            
               if Hend<Hstart
                  dM1(dM1>0)=0;
               end                                 % check physical condition as it is explained in publication [2]
            
               if Hend<Hstart
                  delta=-1;
               else
                  delta=1;
               end         % end of if             % calculate delta - increase or decrease H
            
               dM2 = dM1./(delta.*k-alpha.*dM1_)+c.*dMah_aniso(app, a,Ms,Kan,psi,H+alpha.*M,AnisoType,IntType);  % second part of equation
            
               dM3 = 1+c-c.*alpha.*dMah_aniso(app, a,Ms,Kan,psi,H+alpha.*M,AnisoType,IntType);  % third part of equation
            
               dM3(abs(dM3)<1e-70)=1e-70;
               
               result=dM2./dM3;  % calculate the equation [1]
            
            end % of ModelType==1  
               
               
            if ModelType==2    % Venkataraman
            
               if Hend>Hstart
                  dM1(dM1<0)=0;
               end
            
               if Hend<Hstart
                  dM1(dM1>0)=0;
               end                                 % check physical condition as it is explained in publication [2]
            
               if Hend<Hstart
                  delta=-1;
               else
                  delta=1;
               end         % end of if             % calculate delta - increase or decrease H
            
            
               dM2 = delta.*k.*c.*dMah_aniso(app, a,Ms,Kan,psi,H+alpha.*M,AnisoType,IntType)+dM1;  % second part of equation
            
               dM3 = delta.*k-alpha.*dM1-delta.*k.*c.*alpha.*dMah_aniso(app, a,Ms,Kan,psi,H+alpha.*M,AnisoType,IntType);  % third part of equation
               
               dM3(abs(dM3)>1e-70)=1e-70;
               result=dM2./dM3;                        % calculate the equation [1]
              
            
            end % of ModelType==2            
        end
        
        function result = dMah_aniso(app, a,Ms,Kan,psi,He,AnisoType,IntType)
            %
            % The MIT License (MIT)
            %
            % Copyright (c) 2016 Roman Szewczyk
            %
            % Permission is hereby granted, free of charge, to any person obtaining a copy
            % of this software and associated documentation files (the "Software"), to deal
            % in the Software without restriction, including without limitation the rights
            % to use, copy, modify, merge, publish, distribute, sublicense, and/or sell
            % copies of the Software, and to permit persons to whom the Software is
            % furnished to do so, subject to the following conditions:
            % 
            % The above copyright notice and this permission notice shall be included in all
            % copies or substantial portions of the Software.
            % 
            % THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR
            % IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY,
            % FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE
            % AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER
            % LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM,
            % OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE
            % SOFTWARE.
            % 
            %
            % DESCRIPTION:
            % dMah_aniso is the function for numerical differentiation of anhysteretic, anisotropic magnetization over effective magnetic field He in Jiles-Atherton model
            %
            % AUTHOR: Roman Szewczyk, rszewczyk@onet.pl
            %
            % RELATED PUBLICATION(S):
            % [1] Jiles D. C. , Atherton D. "Theory of ferromagnetic hysteresis" Journal of Magnetism and Magnetic Materials 61 (1986) 48.
            % [2] Ramesh A., Jiles D., Roderik J. "A model of anisotropic anhysteretic magnetization" IEEE Transactions on Magnetics 32 (1996) 4234â€“4236. [Google Scholar] [CrossRef]
            % [3] Ramesh A., Jiles D., Bi Y. "Generalization of hysteresis modeling to anisotropic materials" Journal of Applied Physics  81 (1997) 5585â€“5587.
            % [4] Szewczyk R. "Validation of the Anhysteretic Magnetization Model for Soft Magnetic Materials with Perpendicular Anisotropy" Materials  7 (2014) 5109-5116.
            %
            % USAGE:
            % dM = dMah_aniso(a, Ms, Kan, psi, He, FixedStep)
            % 
            % INPUT:
            % a    - quantifies domain density, A/m (scalar)
            % Ms   - saturation magnetization, A/m (scalar)
            % He   - effective magnetizing field, A/m (scalar, vector. Matrix is not acceptable.)
            % Kan  - average magnetic anisotropy density, K/m3 (scalar)
            % psi  - the angle between a direction of the easy axis of magnetic anisotropy and the direction of the magnetizing field, rad (scalar)
            
            % IntType - select the solver for integration:
            %               0: quadtrapz() - DEFAULT
            %               1: quadgk()
            % OUTPUT:
            % result - value of numerical differentiation of anhysteretic, anisotropic magnetization over effective magnetic field in Jiles-Atherton model, A/m (scalar, vector or matrix, size of He)
            %
            
            if (max(size(a))>1 || max(size(Ms)>1))
              
              fprintf('\n*** ERROR in dMah_aniso: a or Ms is not a scalar. ***\n');
              return
            
            end
            
            if min(size(He))>1
            
              fprintf('\n*** ERROR in dMah_aniso: HeT is a matrix. ***\n');
              return
              
            end  
              
            
            result=(Mah_aniso(app, a, Ms, Kan, psi, He+1e-6,AnisoType,IntType)-Mah_aniso(app, a, Ms, Kan, psi, He-1e-6,AnisoType,IntType))./2e-6;   % value of numerical differentiation
                        
        end
        
        function result = Mah_aniso(app, a, Ms, Kan, psi, HeT, AnisoType,IntType)
            %
            % The MIT License (MIT)
            %
            % Copyright (c) 2016 Roman Szewczyk
            %
            % Permission is hereby granted, free of charge, to any person obtaining a copy
            % of this software and associated documentation files (the "Software"), to deal
            % in the Software without restriction, including without limitation the rights
            % to use, copy, modify, merge, publish, distribute, sublicense, and/or sell
            % copies of the Software, and to permit persons to whom the Software is
            % furnished to do so, subject to the following conditions:
            % 
            % The above copyright notice and this permission notice shall be included in all
            % copies or substantial portions of the Software.
            % 
            % THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR
            % IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY,
            % FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE
            % AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER
            % LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM,
            % OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE
            % SOFTWARE.
            % 
            %
            % DESCRIPTION:
            % Mah_aniso is the function for calculation of anhysteretic, ANISOTROPIC magnetization in Jiles-Atherton model
            %
            % AUTHOR: Roman Szewczyk, rszewczyk@onet.pl
            %
            % RELATED PUBLICATION(S):
            % [1] Jiles D. C. , Atherton D. "Theory of ferromagnetic hysteresis" Journal of Magnetism and Magnetic Materials 61 (1986) 48.
            % [2] Ramesh A., Jiles D., Roderik J. "A model of anisotropic anhysteretic magnetization" IEEE Transactions on Magnetics 32 (1996) 4234â€“4236. [Google Scholar] [CrossRef]
            % [3] Ramesh A., Jiles D., Bi Y. "Generalization of hysteresis modeling to anisotropic materials" Journal of Applied Physics  81 (1997) 5585â€“5587.
            % [4] Szewczyk R. "Validation of the Anhysteretic Magnetization Model for Soft Magnetic Materials with Perpendicular Anisotropy" Materials  7 (2014) 5109-5116.
            %
            % USAGE:
            % M = Mah_aniso(a, Ms, Kan, psi, He)
            % 
            % INPUT:
            % a    - quantifies domain density, A/m (scalar)
            % Ms   - saturation magnetization, A/m (scalar)
            % Kan  - average magnetic anisotropy density, K/m3 (scalar)
            % psi  - the angle between a direction of the easy axis of magnetic anisotropy and the direction of the magnetizing field, rad (scalar)
            % HeT  - effective magnetizing field, A/m (scalar, vector. Matrix is not acceptable)
            % HmeasT - magnetizing field, A/m (set of column vectors - matrix, A/m)
            % a    - quantifies domain density, A/m (scalar)
            % k    - quantifies average energy required to break pinning side, A/m (scalar)
            % c    - magnetization reversibility, 0..1 (scalar)
            % Ms   - saturation magnetization, A/m (scalar)
            % alpha - Bloch coefficient (scalar)
            %
            % 
            % IntType - select the solver for integration:
            %               0: quadtrapz() - DEFAULT
            %               1: quadgk()
            %
            % OUTPUT:
            % result - value of anhysteretic, anisotropic magnetization in Jiles-Atherton model, A/m (scalar, vector or matrix, size of He)
            %
            
            if (max(size(a))>1 || max(size(Ms)>1))
              
              fprintf('\n*** ERROR in Mah_aniso: a or Ms is not a scalar. ***\n');
              return;
              
            end
              
            if (min(size(HeT))>1)
            
              fprintf('\n*** ERROR in Mah_aniso: HeT is a matrix. ***\n');
              return;
              
            end
            
            if (Ms==0) || (a==0)
            
              result=zeros(size(He));
              return;
            
            end  % of if
            
            i=(HeT==0);        % find singularities for He==0
            
            HeT(i)=1;          % remove singularities
            
            if Kan==0
               result=Ms.*(coth(HeT./a)-(a./HeT));   % calculate anhysteretic, isotropic magnetization due to lack of anisotropy
            else
               result=HeT.*0;
            
               for j=1:numel(HeT)
            
                   He=HeT(j);
            
                   if AnisoType==0  % uniaxial anisotropy
                   e1 = @(theta) (He)./a.*cos(theta)-Kan./(Ms.*a.*4.*pi.*1e-7).*((cos(psi-theta)).^2.*(sin(psi-theta)).^2+0.25.*(sin(psi-theta)).^4);
                   e2 = @(theta) (He)./a.*cos(theta)-Kan./(Ms.*a.*4.*pi.*1e-7).*((cos(psi+theta)).^2.*(sin(psi+theta)).^2+0.25.*(sin(psi+theta)).^4);
                   F1 = @(theta) exp(0.5.*(e1(theta)+e2(theta))).*sin(theta).*cos(theta);
                   F2 = @(theta) exp(0.5.*(e1(theta)+e2(theta))).*sin(theta);
                   else   % GO anisotropy
                   e1 = @(theta) (He)./a.*cos(theta)-Kan./(Ms.*a.*4.*pi.*1e-7).*(sin(psi-theta)).^2;
                   e2 = @(theta) (He)./a.*cos(theta)-Kan./(Ms.*a.*4.*pi.*1e-7).*(sin(psi+theta)).^2;
                   F1 = @(theta) exp(0.5.*(e1(theta)+e2(theta))).*sin(theta).*cos(theta);
                   F2 = @(theta) exp(0.5.*(e1(theta)+e2(theta))).*sin(theta);       
                   end
                   
                   
                   if IntType==0
                      p1=quadtrapz(F1,0,pi,90);
                      p2=quadtrapz(F2,0,pi,90);
                   else
                      p1=quadgk(F1,0,pi);
                      p2=quadgk(F2,0,pi);  
                   end
            
                   if ((p2==0) || isnan(p1) || isnan(p2))
                      result(j) = 0; 			% something wrong happen
                   else
                      result(j) = Ms.*p1./p2; 
                   end
                end % end of for
            
            
            end   % of if
            
            
            result(i)=0;      % correct values for singularities
                        
        end
        
        function monitor(app, iteration, optiVal, res)
            %progress
            progress = round((100*iteration)/app.maxIterations);
            app.Gauge.Value = progress;
            %plot
            scatter(app.plotMonitor, iteration, optiVal);
            app.plotMonitor.YScale = 'log';
            drawnow
            %plot(app.plotPlot, app.redH4(:,1), app.redH4(:,2));
            
            %values
            msgNew = sprintf('\nNr.: %d:   a: %d   k: %d   c:%d   alpha: %d   Ms: %d  Func: %d\n\n',iteration, res, optiVal);
            app.monitorString = strcat(app.monitorString,msgNew);
            app.txtValues.Value = app.monitorString;
        end
        
        
        function plotComparisonResult(app)
            cla(app.plotResult);
            
            [row, col] = size(app.selectedLoopArray);
            value = app.ddChooseRes.Value;
            legend(app.plotResult,'off') ;
            if strcmp(app.swChooseRes.Value, 'All')
                app.lblAreaInfo.Visible = 'off';
                switch col
                    case 1
                        plot(app.plotResult, app.redH1(:,1), app.BsimT1);
                        hold(app.plotResult,'on');
                        scatter(app.plotResult, app.redH1(:,1), app.redH1(:,2));
                        
                        legend(app.plotResult, 'Matlab','Measurement');
                        legend(app.plotResult, 'Location','southeast');
                        hold(app.plotResult,'off');
                    case 2
                        plot(app.plotResult, app.redH1(:,1), app.BsimT1);
                        hold(app.plotResult,'on');
                        plot(app.plotResult, app.redH2(:,1), app.BsimT2);
                        scatter(app.plotResult, app.redH1(:,1), app.redH1(:,2));
                        scatter(app.plotResult, app.redH2(:,1), app.redH2(:,2));
                        
                        legend(app.plotResult, 'Matlab 1','Matlab 2', 'Measurement 1','Measurement 2');
                        legend(app.plotResult, 'Location','southeast');
                        hold(app.plotResult,'off');
                    case 3
                        plot(app.plotResult, app.redH1(:,1), app.BsimT1);
                        hold(app.plotResult,'on');
                        plot(app.plotResult, app.redH2(:,1), app.BsimT2);
                        plot(app.plotResult, app.redH3(:,1), app.BsimT3);
                        scatter(app.plotResult, app.redH1(:,1), app.redH1(:,2));
                        scatter(app.plotResult, app.redH2(:,1), app.redH2(:,2));
                        scatter(app.plotResult, app.redH3(:,1), app.redH3(:,2));
                        
                        legend(app.plotResult, 'Matlab 1','Matlab 2','Matlab 3', 'Measurement 1','Measurement 2','Measurement 3');
                        legend(app.plotResult, 'Location','southeast');
                        hold(app.plotResult,'off');                    
                    case 4
                        plot(app.plotResult, app.redH1(:,1), app.BsimT1);
                        hold(app.plotResult,'on');
                        plot(app.plotResult, app.redH2(:,1), app.BsimT2);
                        plot(app.plotResult, app.redH3(:,1), app.BsimT3);
                        plot(app.plotResult, app.redH4(:,1), app.BsimT4);
                        scatter(app.plotResult, app.redH1(:,1), app.redH1(:,2));
                        scatter(app.plotResult, app.redH2(:,1), app.redH2(:,2));
                        scatter(app.plotResult, app.redH3(:,1), app.redH3(:,2));
                        scatter(app.plotResult, app.redH4(:,1), app.redH4(:,2));
                        
                        legend(app.plotResult, 'Matlab 1','Matlab 2','Matlab 3','Matlab 4', 'Measurement 1','Measurement 2','Measurement 3','Measurement 4');
                        legend(app.plotResult, 'Location','southeast');
                        hold(app.plotResult,'off');     
                end
            else
                app.txtaError.Value = 'Select "Plot error" to initialzie the error calculation for this loop!';
                for i = 1:1:col
                    if strcmp(value,app.selectedLoopCellString(i,1))
                        switch i
                            case 1
                                value = app.swErrorPlot.Value;
                                
                                if strcmp(value, 'Plot error')
                                    errorCalculation(app, app.redH1(:,1), app.redH1(:,2), app.BsimT1, true);
                                    hold(app.plotResult,'on');
                                    plot(app.plotResult, app.redH1(:,1), app.BsimT1);
                                    hold(app.plotResult,'off'); 
                                    
                                    app.lblAreaInfo.Visible = 'on';
                                else
                                    plot(app.plotResult, app.redH1(:,1), app.BsimT1);
                                    hold(app.plotResult,'on');
                                    scatter(app.plotResult, app.redH1(:,1), app.redH1(:,2));
                                    
                                    legend(app.plotResult, 'Matlab','Measurement');
                                    legend(app.plotResult, 'Location','southeast');
                                    hold(app.plotResult,'off'); 
                                     
                                    app.lblAreaInfo.Visible = 'off';
                                end
                                
                                                                
                            case 2
                                value = app.swErrorPlot.Value;
                                
                                if strcmp(value, 'Plot error')
                                    errorCalculation(app, app.redH2(:,1), app.redH2(:,2), app.BsimT2, true);
                                    hold(app.plotResult,'on');
                                    plot(app.plotResult, app.redH2(:,1), app.BsimT2);
                                    hold(app.plotResult,'off'); 
                                     
                                    app.lblAreaInfo.Visible = 'on';
                                else
                                    plot(app.plotResult, app.redH2(:,1), app.BsimT2);
                                    hold(app.plotResult,'on');
                                    scatter(app.plotResult, app.redH2(:,1), app.redH2(:,2));
                                    
                                    legend(app.plotResult, 'Matlab','Measurement');
                                    legend(app.plotResult, 'Location','southeast');
                                    hold(app.plotResult,'off'); 
                                    
                                    app.lblAreaInfo.Visible = 'off';
                                end
                                
                            case 3
                                value = app.swErrorPlot.Value;
                                
                                if strcmp(value, 'Plot error')
                                    errorCalculation(app, app.redH3(:,1), app.redH3(:,2), app.BsimT3, true);
                                    hold(app.plotResult,'on');
                                    plot(app.plotResult, app.redH3(:,1), app.BsimT3);
                                    hold(app.plotResult,'off'); 
                                    
                                     
                                    app.lblAreaInfo.Visible = 'on';
                                else
                                    plot(app.plotResult, app.redH3(:,1), app.BsimT3);
                                    hold(app.plotResult,'on');
                                    scatter(app.plotResult, app.redH3(:,1), app.redH3(:,2));
                                    
                                    legend(app.plotResult, 'Matlab','Measurement');
                                    legend(app.plotResult, 'Location','southeast');
                                    hold(app.plotResult,'off'); 
                                    
                                    app.lblAreaInfo.Visible = 'off';
                                end
                                
                            case 4
                                value = app.swErrorPlot.Value;
                                
                                if strcmp(value, 'Plot error')
                                    errorCalculation(app, app.redH4(:,1), app.redH4(:,2), app.BsimT4, true);
                                    hold(app.plotResult,'on');
                                    plot(app.plotResult, app.redH4(:,1), app.BsimT4);
                                    hold(app.plotResult,'off');
                                    
                                     
                                    app.lblAreaInfo.Visible = 'on';
                                else
                                    plot(app.plotResult, app.redH4(:,1), app.BsimT4);
                                    hold(app.plotResult,'on');
                                    scatter(app.plotResult, app.redH4(:,1), app.redH4(:,2));
                                    
                                    legend(app.plotResult, 'Matlab','Measurement');
                                    legend(app.plotResult, 'Location','southeast');
                                    hold(app.plotResult,'off'); 
                                    
                                    app.lblAreaInfo.Visible = 'off';
                                end
                        end
                    end
                end    
            end
        end
        
        function res = errorCalculation(app, HmeasT, BmeasT, BsimT, plot)
          
            %error between comsol and measured
            
            % Making the error plots
            
            turnIndex = HmeasT(1,1);
            for c = 1:(size(HmeasT)-1)
                oldVal = HmeasT(c);
                newVal = HmeasT(c+1);
                if newVal > oldVal
                    turnIndex = c;
                    break;
                end
            end
            
            
           %Must transpose x and y's in order for fill to make polygon
            
            
            % error 
            
            % Making the error plots
            turnIndex = 0;
            polIndex1 = 0;
            polIndex2 = 0;
            
            for c = 1:(size(BmeasT)-1)
                oldVal = BmeasT(c);
                newVal = BmeasT(c+1);
                if newVal > oldVal
                    turnIndex = c;
                    break;
                end
                if polIndex1 == 0 && BmeasT(c,1) <= 0
                    polIndex1 = c;
                end
            end
            for c = turnIndex:(size(BmeasT))
                
                if polIndex2 == 0 && BmeasT(c,1) >= 0
                    polIndex2 = c;
                end
            end
            
            %area for measured curve
            
            %top
            y1=BmeasT(1:polIndex1,1).'; 
            x1=HmeasT(1:polIndex1,1).';
            y2=BmeasT(polIndex2:size(BmeasT),1).'; 
            x2=HmeasT(polIndex2:size(HmeasT),1).'; 
            top = abs(trapz(fliplr(x1),fliplr(y1)))-abs(trapz(fliplr(x2),fliplr(y2)));
            
            %Plot area top: Change first element which will be last after fliplr
            if plot
                y2(length(y2))=y1(1,1);
                x2(length(x2))=x1(1,1);
                
                x2(1) = interpol(app, x2(1), x2(2), y2(1), y2(2));
                y2(1) = 0;
                
                x1(length(x1))=interpol(app, x1(length(x1)), x1(length(x1)-1), y1(length(y1)), y1(length(y1)-1) );
                y1(length(y1))=0;
                
                hold(app.plotResult,'on');
                area(app.plotResult,fliplr(x1),fliplr(y1), 'EdgeColor','none','ShowBaseLine','off');
                area(app.plotResult,x2,y2,'FaceColor','w','EdgeColor','none','ShowBaseLine','off');
                hold(app.plotResult,'off');                
            end
            
            
            %bottom
            y1=BmeasT(turnIndex:polIndex2,1).'; 
            x1=HmeasT(turnIndex:polIndex2,1).'; 
            y2=BmeasT((polIndex1-1):turnIndex,1).'; 
            x2=HmeasT((polIndex1-1):turnIndex,1).';
            bottom = abs(trapz(fliplr(x1),fliplr(y1)))-abs(trapz(fliplr(x2),fliplr(y2)));
            
%             Plot area bottom: Change first element which will be last after fliplr
            if plot
                x1(length(x1)) = interpol(app, x1(length(x1)-1), x1(length(x1)), y1(length(y1)-1), y1(length(y1)));
                y1(length(y1)) = 0;
                
                x2(1) = interpol(app, x2(1), x2(2), y2(1), y2(2));
                y2(1) = 0;
                
                hold(app.plotResult,'on');
                area(app.plotResult,x1,y1, 'EdgeColor','none','ShowBaseLine','off');
                area(app.plotResult,x2,y2,'FaceColor','w','EdgeColor','none','ShowBaseLine','off');
                hold(app.plotResult,'off');
            end


            %total area of measured hysteresis loop
            
            totMeasuredLoopArea = top + bottom;
            
            %area for matlab curve
            
            %top
            y1=BsimT(1:polIndex1,1).'; 
            x1=HmeasT(1:polIndex1,1).';
            y2=BsimT(polIndex2:size(BmeasT),1).'; 
            x2=HmeasT(polIndex2:size(HmeasT),1).'; 
            top = abs(trapz(fliplr(x1),fliplr(y1)))-abs(trapz(fliplr(x2),fliplr(y2)));
            
            %bottom
            y1=BsimT(turnIndex:polIndex2,1).'; 
            x1=HmeasT(turnIndex:polIndex2,1).'; 
            y2=BsimT(polIndex1:turnIndex,1).'; 
            x2=HmeasT(polIndex1:turnIndex,1).';
            bottom = abs(trapz(fliplr(x1),fliplr(y1)))-abs(trapz(fliplr(x2),fliplr(y2)));
            
            %total area of Matlab hysteresis loop
            
            totMatlabLoopArea = top + bottom;


            % Error - Measured and Matlab
            diff = abs(totMeasuredLoopArea - totMatlabLoopArea);
            diffPercent = 100.*(totMatlabLoopArea./totMeasuredLoopArea)-100;
            sum = totMeasuredLoopArea + totMatlabLoopArea;
            
            if plot
                msgError = sprintf('Calculated areas:\nMeasured: %.2f\nMatlab: %.2f\nSum: %.2f\n\nDifference (abs): %.2f\nDifference (%%): %.2f',totMeasuredLoopArea, totMatlabLoopArea, sum, diff, diffPercent);
                app.txtaError.Value = msgError;
            end
            
            res = [totMeasuredLoopArea, totMatlabLoopArea];
        end
        
        function saveToExcel(app, name)
            titleHead = {'Output from JAMPS by Magnus E. Tangen'};
            
            dateHead = {'Date:'};
            paramHead = {'a', 'k', 'c', 'alpha', 'Ms'};
            errorHead = {'Total error (%):'};
            optHead = {'Optimizing settings'};
            itHead = {'Number of iterations:'};
            loopHead = {'Selected loop(s):'};
            solverHead = {'Solver:'};
            reductionHead = {'Reduction (%)'};
            commentHead = {'Comment:'};
            
            xlswrite(app.fileName, [titleHead], name,'A1:A1');
            
            
            xlswrite(app.fileName, [dateHead], name,'A3:A3');
            xlswrite(app.fileName, [{datestr(now)}], name,'B3:B3');
            
            xlswrite(app.fileName, [paramHead], name, 'A5:E5');
            xlswrite(app.fileName, [app.res], name, 'A6:E6');
            
            xlswrite(app.fileName, [errorHead], name, 'A8:A8');
            xlswrite(app.fileName, [app.totPercDif], name, 'B8:B8');
            
            xlswrite(app.fileName, [optHead], name,'A10:A10');
            
            xlswrite(app.fileName, [itHead], name, 'A11:A11');
            xlswrite(app.fileName, [app.maxIterations], name, 'B11:B11');
            
             % need to check how many loops that are in play
            xlswrite(app.fileName, [loopHead], name, 'A12:A12');
            switch size(app.selectedLoopArray,2)
                case 1
                    xlswrite(app.fileName, [app.selectedLoopArray], name, 'B12:B12');
                    
                case 2
                    xlswrite(app.fileName, [app.selectedLoopArray], name, 'B12:C12');
                    
                case 3
                    xlswrite(app.fileName, [app.selectedLoopArray], name, 'B12:D12');
                    
                case 4
                    xlswrite(app.fileName, [app.selectedLoopArray], name, 'B12:E12');
                    
            end
            % Loop check end
            
            xlswrite(app.fileName, [solverHead], name, 'A13:A13');
            
            %Find solver
            switch app.solverType
                case 0
                    xlswrite(app.fileName, [{'ode23'}], name, 'B13:B13');
                case 1
                    xlswrite(app.fileName, [{'ode45'}], name, 'B13:B13');
                case 2
                    xlswrite(app.fileName, [{'ode23s'}], name, 'B13:B13');
                case 3
                    xlswrite(app.fileName, [{'rk4'}], name, 'B13:B13');
            end
            
            xlswrite(app.fileName, [solverHead], name, 'A13:A13');
            xlswrite(app.fileName, [reductionHead], name, 'A14:A14');
            xlswrite(app.fileName, [{app.lblReducePercent.Text}], name, 'B14:B14');
            xlswrite(app.fileName, [commentHead], name, 'A16:A16');
            xlswrite(app.fileName, [app.txtaComment.Value], name, 'B16:B16');
            
            %Adjustment in Excel            
            Excel = actxserver('Excel.Application');
            Excel.Workbooks.Open(app.fileName);
            Excel.DisplayAlerts=0;
            
            [status,sheets,xlFormat] = xlsfinfo(app.fileName);
            openPage = 1;
            for i = 1:1:size(sheets,2)
                if strcmp(sheets(i),name)
                   openPage = i; 
                end
            end
            
            workbook = Excel.Workbooks.Open(app.fileName);
            sheet = workbook.Sheets.Item(openPage);
            
            
            sheet.Range('A1').Font.Bold=1;
            sheet.Range('A1').Font.Size=14;
            sheet.Range('A1').columnWidth=19;
            rgb_val = @(r,g,b) r*1+g*256+b*256^2;
            sheet.Range('A5:E5').Interior.Color = rgb_val(84, 130, 53);
            sheet.Range('A6:E6').Interior.Color = rgb_val(169, 208, 142);
            sheet.Range('B8').Interior.Color = rgb_val(255, 80, 80);
            
            sheet.Range('A10').EntireRow.Font.Bold=1;
            
            
            sheet.Range('B3').ShrinkToFit=1; %Matlab '=1' is actually '= True' in Excel VBA
            
            sheet.Range('A16').Font.Bold=1;
            sheet.Range('B16').VerticalAlignment=-4160 ;
            sheet.Range('B16').Interior.Color = rgb_val(217, 217, 217);
            sheet.Range('B16:D23').MergeCells=1;
            
            
            workbook.Save;
            Quit(Excel);
            delete(Excel);
        end
        
        function res = setMinMs(app)
            
            value = app.cbMs.Value;
            if value
                [row, col] = size(app.selectedLoopArray);
                mi0=4.*pi.*1e-7;
                
                switch col
                    case 1
                        maxH = max(app.redH1(:,1));
                        app.minMs = max(maxH(:,1))./mi0./1000;
                    case 2
                        maxH = zeros(2,1);
                        maxH(1,1) = max(app.redH1(:,1));
                        maxH(2,1) = max(app.redH2(:,1));
                        app.minMs = max(maxH(:,1))./mi0./1000;
                        
                    case 3
                        maxH = zeros(3,1);
                        maxH(1,1) = max(app.redH1(:,1));
                        maxH(2,1) = max(app.redH2(:,1));
                        maxH(3,1) = max(app.redH3(:,1));
                        app.minMs = max(maxH(:,1))./mi0./1000;
                    case 4
                        maxH = zeros(4,1);
                        maxH(1,1) = max(app.redH1(:,1));
                        maxH(2,1) = max(app.redH2(:,1));
                        maxH(3,1) = max(app.redH3(:,1));
                        maxH(4,1) = max(app.redH4(:,1));
                        app.minMs = max(maxH(:,1))./mi0./1000;
                        
                end
                res=app.minMs;
            else
                app.minMs = 0;
                res=1.152e6;
            end
            %fprintf('app.minMs = %f\n', app.minMs);
        end
        
        function interB = interpol(app, x1, x2, y1, y2)
            interB = x1-y1*((x2-x1)/(y2-y1));
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.panImport.Visible = 'on';
           app.panPrecalculation.Visible = 'off';
           app.panAdvanced.Visible = 'off';
           app.panMonitor.Visible = 'off';
           app.panResult.Visible = 'off';
           app.panExist.Visible = 'off';
           
           app.reductionFactor = 1;
           app.solverType = 0;
           app.showPlot = false;
           app.showProgress = true;
           app.showValues = false;
           app.maxIterations = 40;
           app.monitorString = 'Values used in optimization:';
           %app.UIFigure.Icon = 'info';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            reduceMeasurements(app);
            plotLoops(app);
            
            
        end

        % Button pushed function: MonitorButton
        function GoClick(app, event)
            app.panPrecalculation.Visible = 'off';
            app.panMonitor.Visible = 'on';
            app.txtaGuide.Value = {'We are readdy to optimize!'; ''; 'Click "Optimize" to start.'; ''; 'The progress [%] is updated during the calculations. The objective function value will be plotted as it is minimized and each objective function value can be mapped to a set of Jiles-Atherton parameter values.'; '';'When the calculations are done, please click on the green "Results" button'; '';'Good luck!'};
            
            
        end

        % Button pushed function: btnAdvanced
        function btnAdvancedClick(app, event)
            app.panPrecalculation.Visible = 'off';
            app.panAdvanced.Visible = 'on';
        end

        % Button pushed function: btnApply
        function btnApplyClick(app, event)
            app.showPlot = app.cbPlot.Value;
            app.showProgress = app.cbProgress.Value;
            app.showValues = app.cbValues.Value;
            app.maxIterations = app.etxtMaxIteration.Value;
        end

        % Button pushed function: btnBack
        function btnBackClick(app, event)
            app.panPrecalculation.Visible = 'on';
            app.panAdvanced.Visible = 'off';
        end

        % Button pushed function: btnBrowse
        function btnBrowseClick(app, event)
            [filename, pathname] = uigetfile({'*.xls','*.xlsx'},'File Selector');
            app.UIFigure.Visible = 'off'; % To prevent program ending up in background (uigetfile bug)
            app.UIFigure.Visible = 'on';
            app.fileName = strcat(pathname, filename);
            app.lblShowPath.Text = app.fileName;
            app.btnImport.Enable = 'on';
            
        end

        % Button pushed function: btnImport
        function btnImportClick(app, event)
            app.impData = xlsread(app.fileName, 'Hyst. data', 'A:D');
            [row, col] = size(app.impData);
            app.numImpRows = row; 
            
            temp = app.impData(1,1);
            for i = 1:1:app.numImpRows
                if (temp ~= app.impData(i,1)) 
                    if isnan(app.impData(i,1))
                        %nothing
                    else
                        temp = temp + 1;
                    end
                    
                end
            end
            
            msg = 'There are %d measured loops available in the imported file.\n\nSelect maximum 4 loops.\n\nHold ctrl and click to select.\n\nYou have selected: ';
            app.msgNumMeas = sprintf(msg,temp);
            
            msg = 'There are %d measured loops available in the imported file.\n\nSelect maximum 4 loops.\n\nHold ctrl and click to select.\n\nYou have selected: 1';
            app.taInstructions.Value = sprintf(msg,temp);
            
            listItems = zeros(temp, 1);
            for i = 1:1:temp
                listItems(i,1) = i;
            end
            
            app.lbox.Items = cellstr(num2str(listItems));
            app.lbox.Visible = 'on';
            app.lbox.Enable = 'on';
            app.btnUse.Enable = 'on';
        end

        % Button pushed function: btnImportPre
        function btnImportPreClick(app, event)
            app.panPrecalculation.Visible = 'off';
            app.panImport.Visible = 'on';
            app.txtaGuide.Value = {'Welcome to JAMPS!'; ''; 'This is the software that helps you with determining the Jiles-Atherton parameters. '; ''; 'Import the DC-measurements and select the loops you want the calculations to be based on.'};
        end

        % Button pushed function: btnImprove
        function btnImproveClick(app, event)
            app.btnResults.BackgroundColor = [0.96,0.96,0.96];
            app.monitorString = 'Values used in optimization:';
            cla(app.plotMonitor);
            multipleLoopsDifferentLength(app, true);
        end

        % Button pushed function: btnMonitorPlot
        function btnMonitorPlotClick(app, event)
            app.panPrecalculation.Visible = 'on';
            app.panResult.Visible = 'off';
        end

        % Button pushed function: btnOptimize
        function btnOptimizeClick(app, event)
            app.btnResults.BackgroundColor = [0.96,0.96,0.96];
            app.monitorString = 'Values used in optimization:';
            
            cla(app.plotMonitor);
            multipleLoopsDifferentLength(app, false);
        end

        % Button pushed function: btnPreMonitor
        function btnPreMonitorClick(app, event)
            app.panMonitor.Visible = 'off';
            app.panPrecalculation.Visible = 'on';
            app.txtaGuide.Value = {'Now you have selected the loops.'; '';'Invetsigate the loops and if you want to change the selected loops, press "Back". '; '';'It is recommended to use the reduction option in order to speed up the calculations. '; '';'If you want to change the solver, number of iterations or  set a maximum Ms value: press "Advanced settings"'};
            
        end

        % Button pushed function: btnResExist
        function btnResExistClick(app, event)
            app.panExist.Visible = 'off';
            app.panResult.Visible = 'on';
        end

        % Button pushed function: btnResults
        function btnResultsClick(app, event)
            app.panPrecalculation.Visible = 'off';
            app.panResult.Visible = 'on';
            app.btnImprove.Visible = 'on';
            app.txtaGuide.Value = {'Check your results. You can investigate the different loops (Jiles-Atherton and measured) by flicking the switch to "Select pair".'; '';'By flicking the second switch to "Plot error" you can investigate the difference in the hysteresis integrals of th eselected pair'; '';'By checknig the "Add comment" check box you can add a comment in the result.';'';'If you want to improve your result press "Back" and "Improve". It is possible to go further back and make other adjustments before you press "Improve".';'';'Make sure that the excel-file is closed before you press "Save".'};
        end

        % Button pushed function: btnSave
        function btnSaveClick(app, event)
            [A,B] = xlsfinfo(app.fileName);
            if any(strcmp(B, 'Result'))
                app.panResult.Visible = 'off';
                app.panExist.Visible = 'on';
            else
                saveToExcel(app, 'Result');
            end
        end

        % Button pushed function: btnSaveNew
        function btnSaveNewClick(app, event)
            value = app.swOverride.Value;
            if strcmp(value, 'Save as new')
               if strcmp(app.edtSaveAs.Value,'')
                   app.lblSaveAs.Text = 'Enter name';
               else
                   [A,B] = xlsfinfo(app.fileName);
                    if any(strcmp(B, app.edtSaveAs.Value))
                        app.lblSaveAs.Text = 'Name exists';
                    else
                        saveToExcel(app, app.edtSaveAs.Value);
                    end
               end
            else
               saveToExcel(app, 'Result');
            end
        end

        % Button pushed function: btnUse
        function btnUseClick(app, event)
            app.panImport.Visible = 'off';
            app.panPrecalculation.Visible = 'on';
            app.txtaGuide.Value = {'Now you have selected the loops.'; '';'Invetsigate the loops and if you want to change the selected loops, press "Back". '; '';'It is recommended to use the reduction option in order to speed up the calculations. '; '';'If you want to change the solver, number of iterations or  set a maximum Ms value: press "Advanced settings"'};
            
            value = app.lbox.Value;
            [row, col] = size(value);
            app.numLoopRows = ones(1,col);
            
            selectedLoops = zeros(1, col);
            for i = 1:1:col
               selectedLoops(1,i) = str2double(strtrim(value(1,i))); 
            end


            counter = 0;
            for i = 1:1:col
                for j = 1:1:app.numImpRows
                    if (selectedLoops(1,i) == app.impData(j,1))
                        counter = counter + 1;
                    end
                end
                app.numLoopRows(1,i) = counter;
                counter = 0;
            end
            app.UITable.ColumnName = selectedLoops;
            app.selectedLoopArray = selectedLoops;
            app.selectedLoopCellString = cellstr(num2str(transpose(selectedLoops)));
            
            app.ddChooseRes.Items = app.selectedLoopCellString;
            app.DropDown.Items = app.selectedLoopCellString;
            app.UITable.Data = app.numLoopRows;
            
            %Read data into variables:
            %DIVIDE app.impData(j,4) by 1000 due to mT in Excel sheet
            counter = 0;
            for i = 1:1:col
                switch i
                    case 1
                        app.Hyst1 = zeros(app.numLoopRows(1, 1), 2);
                        
                        for j = 1:1:app.numImpRows
                            if (selectedLoops(1,1) == app.impData(j,1))
                                counter = counter + 1;
                                app.Hyst1(counter, 1) = app.impData(j,3);
                                app.Hyst1(counter, 2) = app.impData(j,4)/1000;
                            end
                        end
                        counter = 0;
                        
                    case 2
                        app.Hyst2 = zeros(app.numLoopRows(1, 2), 2);
                        
                        for j = 1:1:app.numImpRows
                            if (selectedLoops(1,2) == app.impData(j,1))
                                counter = counter + 1;
                                app.Hyst2(counter, 1) = app.impData(j,3);
                                app.Hyst2(counter, 2) = app.impData(j,4)/1000;
                            end
                        end
                        counter = 0;
                        
                    case 3
                        app.Hyst3 = zeros(app.numLoopRows(1, 3), 2);
                        
                        for j = 1:1:app.numImpRows
                            if (selectedLoops(1,3) == app.impData(j,1))
                                counter = counter + 1;
                                app.Hyst3(counter, 1) = app.impData(j,3);
                                app.Hyst3(counter, 2) = app.impData(j,4)/1000;
                            end
                        end
                        counter = 0;
                        
                    case 4
                        app.Hyst4 = zeros(app.numLoopRows(1, 4), 2);
                         
                        for j = 1:1:app.numImpRows
                            if (selectedLoops(1,4) == app.impData(j,1))
                                counter = counter + 1;
                                app.Hyst4(counter, 1) = app.impData(j,3);
                                app.Hyst4(counter, 2) = app.impData(j,4)/1000;
                            end
                        end
                        counter = 0;
                end
            end
            % reductionFactor is set
            
            % Reduce plottable values
            reduceMeasurements(app);
            % Plot (and figure out the mode)
            plotLoops(app);
            
        end

        % Value changed function: cbAddComment
        function cbAddCommentChange(app, event)
            value = app.cbAddComment.Value;
            if value
                app.txtaComment.Visible = 'on';
                app.lblComment.Visible = 'on';
            else
                app.txtaComment.Visible = 'off';
                app.lblComment.Visible = 'off';
                app.txtaComment.Value = 'No comment linked to this result.';
            end
        end

        % Value changed function: cbProgress
        function cbProgressChanged(app, event)
            value = app.cbProgress.Value;
            if value
                app.ProgressPanel.Visible = 'on';
            else
                app.ProgressPanel.Visible = 'off';
            end
        end

        % Value changed function: cbValues
        function cbValuesChange(app, event)
            value = app.cbValues.Value;
            if value
                app.panValuesRes.Visible = 'on';
            else
                app.panValuesRes.Visible = 'off';
            end
        end

        % Value changed function: cbPlot
        function cpPlotChange(app, event)
            value = app.cbPlot.Value;
            if value
                app.panPlotRes.Visible = 'on';
            else
                app.panPlotRes.Visible = 'off';
            end
        end

        % Value changed function: ddChooseRes
        function ddChooseResChange(app, event)
            plotComparisonResult(app);
        end

        % Value changed function: ddSolver
        function ddSolverChange(app, event)
            app.solverType = str2num(app.ddSolver.Value);
            
        end

        % Callback function
        function lboxSelected(app, event)
            value = app.lbox.Value;
            
            [row, col] = size(value);
            if col <= 4
               app.taInstructions.Value = strcat(app.msgNumMeas, num2str(col));
               app.btnUse.Enable = 'on';
               app.taInstructions.BackgroundColor = [1 1 1];
               
            elseif col >= 5
               app.taInstructions.Value = strcat(app.msgNumMeas, num2str(col)); 
               app.btnUse.Enable = 'off';
               app.taInstructions.BackgroundColor = [1 0 0];
            end
            
        end

        % Value changed function: spinnReduce
        function spinnReduceChange(app, event)
            value = app.spinnReduce.Value;
            app.reductionFactor = value;
            app.lblReducePercent.Text = num2str(100-(1/value)*100);
            
            reduceMeasurements(app);
            plotLoops(app);
                        
            
        end

        % Value changed function: swChooseRes
        function swChooseResChange(app, event)
            value = app.swChooseRes.Value;
            cla(app.plotMonitor);
            if strcmp(value, 'All')
                app.ddChooseRes.Enable = 'off';
                app.swErrorPlot.Enable = 'off';
                app.swErrorPlot.Value = 'Normal';
                
                plotComparisonResult(app);
                
                msgError = sprintf('Total calculated areas:\nMeasured: %.2f\nMatlab: %.2f\nSum: %.2f\n\nDifference (%%): %.2f',app.totMeasArea, app.totMatlabArea, app.totAllArea, app.totPercDif);
                app.txtaError.Value = msgError;
            else
                app.ddChooseRes.Enable = 'on';
                plotComparisonResult(app);
                app.swErrorPlot.Enable = 'on';
            end
        end

        % Value changed function: swErrorPlot
        function swErrorPlotChange(app, event)
            value = app.swErrorPlot.Value;
            plotComparisonResult(app);
            
        end

        % Value changed function: swOverride
        function swOverrideChange(app, event)
            value = app.swOverride.Value;
            if strcmp(value, 'Save as new')
               app.edtSaveAs.Visible = 'on';
               app.lblSaveAs.Visible = 'on';
            else
               app.edtSaveAs.Visible = 'off';
               app.lblSaveAs.Visible = 'off';
            end
        end

        % Value changed function: swReduce
        function swReduceFlick(app, event)
            value = app.swReduce.Value;
            if strcmp(value, 'Off')
                app.spinnReduce.Enable = 'off';
                app.lblReductionInfo.Enable = 'off';
                app.lblReductionInfoPercent.Enable = 'off';
                app.lblReducePercent.Enable = 'off';
                
                app.UITable.Data = app.numLoopRows;
                app.spinnReduce.Value = 1;
                app.reductionFactor = 1;
                
                reduceMeasurements(app);
                plotLoops(app);
                app.lblReducePercent.Text = num2str(100-(1/app.spinnReduce.Value)*100);
            else
                app.spinnReduce.Enable = 'on';
                app.lblReductionInfo.Enable = 'on';
                app.lblReductionInfoPercent.Enable = 'on';
                app.lblReducePercent.Enable = 'on';
                
                app.spinnReduce.Value = 20;
                app.reductionFactor = 20;
                
                reduceMeasurements(app);
                plotLoops(app);
                app.lblReducePercent.Text = num2str(100-(1/app.spinnReduce.Value)*100);
            end
        end

        % Value changed function: Switch
        function switchFlick(app, event)
            value = app.Switch.Value;
            
            if strcmp(value, 'Selected')
               app.DropDown.Enable = 'on';
               reduceMeasurements(app);
               plotLoops(app);
            else
               app.DropDown.Enable = 'off';
               reduceMeasurements(app);
               plotLoops(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'JAMPS by Magnus E. Tangen';
            app.panImport = uipanel(app.UIFigure);
            app.panImport.Title = 'Import';
            app.panImport.Visible = 'off';
            app.BrowsePanel = uipanel(app.panImport);
            app.BrowsePanel.Title = 'Browse';
            app.SelectfilexlsorxlsxLabel = uilabel(app.BrowsePanel);
            app.SelectfilexlsorxlsxLabel.Text = 'Select file (.xls or .xlsx)';
            app.ImportselectedfileLabel = uilabel(app.BrowsePanel);
            app.ImportselectedfileLabel.Text = 'Import selected file';
            app.SelectedfileLabel = uilabel(app.BrowsePanel);
            app.SelectedfileLabel.Text = 'Selected file';
            app.lblShowPath = uilabel(app.BrowsePanel);
            app.lblShowPath.Text = 'No file selected..';
            app.btnBrowse = uibutton(app.BrowsePanel, 'push');
            app.btnBrowse.ButtonPushedFcn = createCallbackFcn(app, @btnBrowseClick, true);
            app.btnBrowse.Text = 'Browse';
            app.btnImport = uibutton(app.BrowsePanel, 'push');
            app.btnImport.ButtonPushedFcn = createCallbackFcn(app, @btnImportClick, true);
            app.btnImport.Enable = 'off';
            app.btnImport.Text = 'Import';
            app.panSelect = uipanel(app.panImport);
            app.panSelect.Title = 'Select measurements';
            app.AvailablemesurementsLabel = uilabel(app.panSelect);
            app.AvailablemesurementsLabel.Text = {'Available '; 'mesurements'};
            app.lbox = uilistbox(app.panSelect);
            app.lbox.Items = {'Missing file..'};
            app.lbox.Multiselect = 'on';
            app.lbox.ValueChangedFcn = createCallbackFcn(app, @lboxSelected, true);
            app.lbox.Enable = 'off';
            app.lbox.Visible = 'off';
            app.lbox.Value = {'Missing file..'};
            app.InstructionsTextAreaLabel = uilabel(app.panSelect);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.taInstructions = uitextarea(app.panSelect);
            app.taInstructions.Editable = 'off';
            app.btnUse = uibutton(app.panSelect, 'push');
            app.btnUse.ButtonPushedFcn = createCallbackFcn(app, @btnUseClick, true);
            app.btnUse.Enable = 'off';
            app.btnUse.Text = 'Pre-calculation';
            app.panPrecalculation = uipanel(app.UIFigure);
            app.panPrecalculation.Title = 'Pre-calculation';
            app.SelectedmeasurementsLabel = uilabel(app.panPrecalculation);
            app.SelectedmeasurementsLabel.Text = 'Selected measurements';
            app.NumberofmeasurementsLabel = uilabel(app.panPrecalculation);
            app.NumberofmeasurementsLabel.Text = 'Number of measurements';
            app.DropDown = uidropdown(app.panPrecalculation);
            app.DropDown.Items = {'Line', 'Scatter', 'Line and scatter'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Line';
            app.UIAxes = uiaxes(app.panPrecalculation);
            title(app.UIAxes, 'Measured hysteresis')
            xlabel(app.UIAxes, 'H [A/m]')
            ylabel(app.UIAxes, 'B [T]')
            app.PlotoptionsLabel = uilabel(app.panPrecalculation);
            app.PlotoptionsLabel.Text = 'Plot options';
            app.Switch = uiswitch(app.panPrecalculation, 'toggle');
            app.Switch.Items = {'Selected', 'All'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @switchFlick, true);
            app.Switch.Value = 'All';
            app.spinnReduce = uispinner(app.panPrecalculation);
            app.spinnReduce.ValueChangedFcn = createCallbackFcn(app, @spinnReduceChange, true);
            app.spinnReduce.Limits = [1 20];
            app.spinnReduce.ValueDisplayFormat = '%.0f';
            app.spinnReduce.Editable = 'off';
            app.spinnReduce.Enable = 'off';
            app.spinnReduce.Value = 1;
            app.UITable = uitable(app.panPrecalculation);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'};
            app.UITable.RowName = {};
            app.lblReductionInfoPercent = uilabel(app.panPrecalculation);
            app.lblReductionInfoPercent.Enable = 'off';
            app.lblReductionInfoPercent.Text = 'Measurement reduction [%]:';
            app.lblReductionInfo = uilabel(app.panPrecalculation);
            app.lblReductionInfo.Enable = 'off';
            app.lblReductionInfo.Text = 'Use every Nth measurement:';
            app.lblReducePercent = uilabel(app.panPrecalculation);
            app.lblReducePercent.Enable = 'off';
            app.lblReducePercent.Text = '';
            app.swReduce = uiswitch(app.panPrecalculation, 'slider');
            app.swReduce.ValueChangedFcn = createCallbackFcn(app, @swReduceFlick, true);
            app.ReductionSwitchLabel = uilabel(app.panPrecalculation);
            app.ReductionSwitchLabel.Text = 'Reduction';
            app.MonitorButton = uibutton(app.panPrecalculation, 'push');
            app.MonitorButton.ButtonPushedFcn = createCallbackFcn(app, @GoClick, true);
            app.MonitorButton.Text = 'Monitor';
            app.btnAdvanced = uibutton(app.panPrecalculation, 'push');
            app.btnAdvanced.ButtonPushedFcn = createCallbackFcn(app, @btnAdvancedClick, true);
            app.btnAdvanced.Text = 'Advanced options';
            app.btnImportPre = uibutton(app.panPrecalculation, 'push');
            app.btnImportPre.ButtonPushedFcn = createCallbackFcn(app, @btnImportPreClick, true);
            app.btnImportPre.Text = 'Back';
            app.panAdvanced = uipanel(app.UIFigure);
            app.panAdvanced.Title = 'Advanced options';
            app.panAdvanced.Visible = 'off';
            app.MaxiterationEditFieldLabel = uilabel(app.panAdvanced);
            app.MaxiterationEditFieldLabel.Text = 'Max iteration';
            app.etxtMaxIteration = uieditfield(app.panAdvanced, 'numeric');
            app.etxtMaxIteration.Limits = [1 1000000];
            app.etxtMaxIteration.RoundFractionalValues = 'on';
            app.etxtMaxIteration.ValueDisplayFormat = '%.0f';
            app.etxtMaxIteration.Value = 40;
            app.cbProgress = uicheckbox(app.panAdvanced);
            app.cbProgress.ValueChangedFcn = createCallbackFcn(app, @cbProgressChanged, true);
            app.cbProgress.Text = 'Progress (%)';
            app.cbProgress.Value = true;
            app.MonitorsettingsduringcalculationLabel = uilabel(app.panAdvanced);
            app.MonitorsettingsduringcalculationLabel.Text = 'Monitor settings (during calculation)';
            app.cbPlot = uicheckbox(app.panAdvanced);
            app.cbPlot.ValueChangedFcn = createCallbackFcn(app, @cpPlotChange, true);
            app.cbPlot.Text = 'Plot (objective function)';
            app.cbPlot.Value = true;
            app.cbValues = uicheckbox(app.panAdvanced);
            app.cbValues.ValueChangedFcn = createCallbackFcn(app, @cbValuesChange, true);
            app.cbValues.Text = 'Current parameter values';
            app.cbValues.Value = true;
            app.SolverconfigurationLabel = uilabel(app.panAdvanced);
            app.SolverconfigurationLabel.Text = 'Solver configuration';
            app.btnApply = uibutton(app.panAdvanced, 'push');
            app.btnApply.ButtonPushedFcn = createCallbackFcn(app, @btnApplyClick, true);
            app.btnApply.Text = 'Apply settings';
            app.btnBack = uibutton(app.panAdvanced, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBackClick, true);
            app.btnBack.Text = 'Back';
            app.SolverLabel = uilabel(app.panAdvanced);
            app.SolverLabel.Text = 'Solver';
            app.ddSolver = uidropdown(app.panAdvanced);
            app.ddSolver.Items = {'ode23', 'ode45', 'ode23s', 'rk4'};
            app.ddSolver.ItemsData = {'0', '1', '2', '3'};
            app.ddSolver.ValueChangedFcn = createCallbackFcn(app, @ddSolverChange, true);
            app.ddSolver.Value = '0';
            app.cbMs = uicheckbox(app.panAdvanced);
            app.cbMs.Text = '';
            app.LowerboundaryMstomaxLabel = uilabel(app.panAdvanced);
            app.LowerboundaryMstomaxLabel.Text = 'Lower boundary Ms to max';
            app.panMonitor = uipanel(app.UIFigure);
            app.panMonitor.Title = 'Monitor';
            app.panMonitor.Visible = 'off';
            app.panPlotRes = uipanel(app.panMonitor);
            app.panPlotRes.Title = 'Plot optimizing function';
            app.plotMonitor = uiaxes(app.panPlotRes);
            xlabel(app.plotMonitor, 'Iteration number')
            ylabel(app.plotMonitor, 'Objective function value')
            app.plotMonitor.LineStyleOrder = {'- '};
            app.panValuesRes = uipanel(app.panMonitor);
            app.panValuesRes.Title = 'Parameter values';
            app.txtValues = uitextarea(app.panValuesRes);
            app.txtValues.Editable = 'off';
            app.ProgressPanel = uipanel(app.panMonitor);
            app.ProgressPanel.Title = 'Progress [%]';
            app.Gauge = uigauge(app.ProgressPanel, 'semicircular');
            app.btnImprove = uibutton(app.panMonitor, 'push');
            app.btnImprove.ButtonPushedFcn = createCallbackFcn(app, @btnImproveClick, true);
            app.btnImprove.Visible = 'off';
            app.btnImprove.Text = 'Improve';
            app.btnPreMonitor = uibutton(app.panMonitor, 'push');
            app.btnPreMonitor.ButtonPushedFcn = createCallbackFcn(app, @btnPreMonitorClick, true);
            app.btnPreMonitor.Text = 'Back';
            app.btnResults = uibutton(app.panMonitor, 'push');
            app.btnResults.ButtonPushedFcn = createCallbackFcn(app, @btnResultsClick, true);
            app.btnResults.Visible = 'off';
            app.btnResults.Text = 'Results';
            app.btnOptimize = uibutton(app.panMonitor, 'push');
            app.btnOptimize.ButtonPushedFcn = createCallbackFcn(app, @btnOptimizeClick, true);
            app.btnOptimize.Text = 'Optimize';
            app.panResult = uipanel(app.UIFigure);
            app.panResult.Title = 'Results';
            app.panResult.Visible = 'off';
            app.tabResult = uitable(app.panResult);
            app.tabResult.ColumnName = {'a'; 'k'; 'c'; 'alpha'; 'Ms'};
            app.tabResult.RowName = {};
            app.plotResult = uiaxes(app.panResult);
            xlabel(app.plotResult, 'H [A/m]')
            ylabel(app.plotResult, 'B [T]')
            app.JilesAthertonparametervaluesLabel = uilabel(app.panResult);
            app.JilesAthertonparametervaluesLabel.Text = 'Jiles-Atherton parameter values';
            app.ddChooseRes = uidropdown(app.panResult);
            app.ddChooseRes.ValueChangedFcn = createCallbackFcn(app, @ddChooseResChange, true);
            app.ddChooseRes.Enable = 'off';
            app.swChooseRes = uiswitch(app.panResult, 'toggle');
            app.swChooseRes.Items = {'Select pair', 'All'};
            app.swChooseRes.ValueChangedFcn = createCallbackFcn(app, @swChooseResChange, true);
            app.swChooseRes.Value = 'All';
            app.btnMonitorPlot = uibutton(app.panResult, 'push');
            app.btnMonitorPlot.ButtonPushedFcn = createCallbackFcn(app, @btnMonitorPlotClick, true);
            app.btnMonitorPlot.Text = 'Back';
            app.btnSave = uibutton(app.panResult, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveClick, true);
            app.btnSave.Text = 'Save';
            app.PlotresultLabel = uilabel(app.panResult);
            app.PlotresultLabel.Text = 'Plot result';
            app.swErrorPlot = uiswitch(app.panResult, 'toggle');
            app.swErrorPlot.Items = {'Normal', 'Plot error'};
            app.swErrorPlot.ValueChangedFcn = createCallbackFcn(app, @swErrorPlotChange, true);
            app.swErrorPlot.Enable = 'off';
            app.swErrorPlot.Value = 'Normal';
            app.txtaError = uitextarea(app.panResult);
            app.txtaError.Editable = 'off';
            app.ErrorLabel = uilabel(app.panResult);
            app.ErrorLabel.Text = 'Error';
            app.cbAddComment = uicheckbox(app.panResult);
            app.cbAddComment.ValueChangedFcn = createCallbackFcn(app, @cbAddCommentChange, true);
            app.cbAddComment.Text = 'Add comment';
            app.lblAreaInfo = uilabel(app.panResult);
            app.lblAreaInfo.Visible = 'off';
            app.lblAreaInfo.Text = 'Filled area of measured loop vs. simulated values (solid line)';
            app.txtaComment = uitextarea(app.UIFigure);
            app.txtaComment.Visible = 'off';
            app.txtaComment.Value = {'No comment linked to this result.'};
            app.lblComment = uilabel(app.UIFigure);
            app.lblComment.Visible = 'off';
            app.lblComment.Text = 'Comment the result';
            app.panExist = uipanel(app.UIFigure);
            app.panExist.Title = 'Existing result';
            app.panExist.Visible = 'off';
            app.edtSaveAs = uieditfield(app.panExist, 'text');
            app.edtSaveAs.Visible = 'off';
            app.btnSaveNew = uibutton(app.panExist, 'push');
            app.btnSaveNew.ButtonPushedFcn = createCallbackFcn(app, @btnSaveNewClick, true);
            app.btnSaveNew.Text = 'Save';
            app.swOverride = uiswitch(app.panExist, 'toggle');
            app.swOverride.Items = {'Save as new', 'Override existing result'};
            app.swOverride.ValueChangedFcn = createCallbackFcn(app, @swOverrideChange, true);
            app.swOverride.Value = 'Override existing result';
            app.lblSaveAs = uilabel(app.panExist);
            app.lblSaveAs.Visible = 'off';
            app.lblSaveAs.Text = 'Save as:';
            app.btnResExist = uibutton(app.panExist, 'push');
            app.btnResExist.ButtonPushedFcn = createCallbackFcn(app, @btnResExistClick, true);
            app.btnResExist.Text = 'Back';
            app.GuidePanel = uipanel(app.UIFigure);
            app.GuidePanel.Title = 'Guide';
            app.txtaGuide = uitextarea(app.GuidePanel);
            app.txtaGuide.Value = {'Welcome to JAMPS!'; ''; 'This is the software that helps you with determining the Jiles-Atherton parameters. '; ''; 'Import the DC-measurements and select the loops you want the calculations to be based on.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = JAMPS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end